classdef SlugPen_AppDesigner2018_working < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        H_Figure                        matlab.ui.Figure
        H_panel_controls                matlab.ui.container.Panel
        H_panel_info                    matlab.ui.container.Panel
        H_filename                      matlab.ui.control.EditField
        H_start_date                    matlab.ui.control.EditField
        H_start_time                    matlab.ui.control.EditField
        H_end_time                      matlab.ui.control.EditField
        H_logint                        matlab.ui.control.EditField
        H_decay                         matlab.ui.control.EditField
        H_pulse                         matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        StartTimeEditFieldLabel         matlab.ui.control.Label
        LogIntsEditFieldLabel           matlab.ui.control.Label
        PulsesecEditFieldLabel          matlab.ui.control.Label
        StartDateEditFieldLabel         matlab.ui.control.Label
        EndTimeEditFieldLabel           matlab.ui.control.Label
        DecayminEditFieldLabel          matlab.ui.control.Label
        H_panel_selected                matlab.ui.container.Panel
        H_Select                        matlab.ui.control.DropDown
        H_eqpenhp_label                 matlab.ui.control.Label
        H_StartDown                     matlab.ui.control.EditField
        H_EndDown                       matlab.ui.control.EditField
        H_StartUp                       matlab.ui.control.EditField
        H_EndUp                         matlab.ui.control.EditField
        H_StartEqm                      matlab.ui.control.EditField
        H_EndEqm                        matlab.ui.control.EditField
        H_Pen                           matlab.ui.control.EditField
        H_Heat                          matlab.ui.control.EditField
        H_End                           matlab.ui.control.EditField
        UpcastDowncastLabel             matlab.ui.control.Label
        DowncastStartLabel              matlab.ui.control.Label
        DowncastEndEditFieldLabel       matlab.ui.control.Label
        UpcastStartEditFieldLabel       matlab.ui.control.Label
        UpcastEndEditFieldLabel         matlab.ui.control.Label
        StartEquilibriumW1EditFieldLabel  matlab.ui.control.Label
        EndEquilibriumW2EditFieldLabel  matlab.ui.control.Label
        StartPenetrationPEditFieldLabel  matlab.ui.control.Label
        HeatPulseHEditFieldLabel        matlab.ui.control.Label
        EndPenetrationEEditFieldLabel   matlab.ui.control.Label
        Selection_ResetButton           matlab.ui.control.Button
        Selection_BeginButton           matlab.ui.control.Button
        H_panel_plotcontrols            matlab.ui.container.Panel
        H_decp                          matlab.ui.control.CheckBox
        H_clnp                          matlab.ui.control.CheckBox
        H_rawp                          matlab.ui.control.CheckBox
        H_zoom                          matlab.ui.control.CheckBox
        H_pan                           matlab.ui.control.CheckBox
        H_quit                          matlab.ui.control.Button
        H_screengrab                    matlab.ui.control.Button
        DataControlsPanel               matlab.ui.container.Panel
        H_crop                          matlab.ui.control.Button
        H_export_H2O                    matlab.ui.control.Button
        H_export_penfile                matlab.ui.control.Button
        H_export_ascii                  matlab.ui.control.Button
        H_Delete                        matlab.ui.control.Button
        NumberofThermistorsEditFieldLabel  matlab.ui.control.Label
        H_Number_of_Thermistors         matlab.ui.control.NumericEditField
        FilterWindowLengthsEditFieldLabel  matlab.ui.control.Label
        H_FilterWindowLengths           matlab.ui.control.EditField
        H_restart                       matlab.ui.control.Button
        H_load                          matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        H_axis_depth                    matlab.ui.control.UIAxes
        H_axis_accel                    matlab.ui.control.UIAxes
        H_axis_tilt                     matlab.ui.control.UIAxes
        H_axis_raw                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Version = '1.0'  % Version of code
        Update = '05.05.2017' % Can change this to 2022 if get code to work
        
        
        % Define global handles, aka PROPERTIES in case of App Designer %
        %filterwindows

        % Properties for vertical line selections %
        VerticalLine
        roi
        x
        selTime
        selTime_Vector
        SD_VerticalLine
        ED_VerticalLine
        SU_VerticalLine
        EU_VerticalLine
        SE_VerticalLine
        EE_VerticalLine
        SP_VerticalLine
        HP_VerticalLine
        EP_VerticalLine
        
        % Properties for Initializing %

        % Previously hidden handles. Initial conditions all set to NaN in the start up function % 
        All_Zeros
        All_NaN
        H_Selected_raw 
        H_Selected_depth
        H_Selected_accel
        H_Selected_tilt
        H_Selected_raw_text
        H_Selected_depth_text
        H_Selected_accel_text
        H_Selected_tilt_text
        H_LegendSelect
        H_AxLims_Raw
        H_AxLims_Tilt
        H_AxLims_Depth
        H_AxLims_Acc
        H_NoThermistors

        % Previously Data. Initial conditions all set to zero in the start up function %        
        %%Traw
        %Tcln
        %Pitch
       % Roll
        %G
       % Tilt
       % Time
        ErrorFlag
        CropFlag
        DATA 

        % Preveiously set to {'default'} - I think should swtich to
        % {'test'} for app designer (but check)
        Params 
        ParamsVals
        Parameters
       
  
        % Inital structures %
        S_Axes = struct()
        S_AxLims = struct()
        S_Exe_Controls = struct()
        S_Selections = struct()
        S_Plot_Controls = struct()
        S_Selections_Lines = struct()
        S_FileInfo = struct()
        H = struct()
        
        % Write pen file structure of info given by user in dialog box %
        S_PenHandles % Penetration structure  
        H_cruisename % User input cruise name
        H_station
        H_penetration
        H_sensor
        H_datum
        H_instrument
        H_thermistors
        H_lat
        H_lon
        H_writepenfile
        H_tilt
        H_depth

        SlugPen_CreatePenFileDialogBox_working % Dialog Box app for creating penfile
    end

    methods (Access = private)
        
        function SelectVerticalLines(app)
        zoom(app.H_axis_raw, 'off')
        pan(app.H_axis_raw, 'off')
        enableDefaultInteractivity(app.H_axis_raw);
        app.x = app.H_Select.Value;
        %%%%%%%%  MATLAB 2019 or LATER  %%%%%%%%
        %app.roi = drawpoint(app.H_axis_raw); % Must have image processing toolbox, only works with UIAxes in 2019 or later
        %app.VerticalLine = xline(app.H_axis_raw, app.roi.Position(1)); 
        %app.selTime = app.roi.Position(1);
        
        %%%%%%%% MATLAB 2018 or EARLIER %%%%%%%%
        app.roi = ginput(app.H_axis_raw, 1); %--> Only for matlab version 2018b or earlier
        app.VerticalLine = xline(app.H_axis_raw, app.roi(1)); 
        app.selTime = app.roi(1);
        
        app.selTime = datestr(app.selTime, 'mm/dd/yyyy HH:MM:SS');
        delete(app.roi)
        app.VerticalLine.Label = app.x;
        app.VerticalLine.LabelHorizontalAlignment = 'center';
        drawnow;
        switch app.x
            case 'Downcast Start'
                app.H.Selections.Start_Downcast.Value = num2str(app.selTime);
                delete(app.SD_VerticalLine)
                app.SD_VerticalLine = app.VerticalLine;
            case 'Downcast End'
                app.H.Selections.End_Downcast.Value = num2str(app.selTime);
                delete(app.ED_VerticalLine)
                app.ED_VerticalLine = app.VerticalLine;
            case 'Upcast Start'
                app.H.Selections.Start_Upcast.Value = num2str(app.selTime);
                delete(app.SU_VerticalLine)
                app.SU_VerticalLine = app.VerticalLine;
            case 'Upcast End'
                app.H.Selections.End_Upcast.Value = num2str(app.selTime);
                delete(app.EU_VerticalLine)
                app.EU_VerticalLine = app.VerticalLine;
            case 'Start Equilibrium (W1)'
                app.H.Selections.Start_Eqm.Value = num2str(app.selTime);
                delete(app.SE_VerticalLine)
                app.SE_VerticalLine = app.VerticalLine;
            case 'End Equilibrium (W2)'
                app.H.Selections.End_Eqm.Value = num2str(app.selTime);
                delete(app.EE_VerticalLine )
                app.EE_VerticalLine = app.VerticalLine;
            case 'Start Penetration (P)'
                app.H.Selections.Start_Pen.Value = num2str(app.selTime);
                delete(app.SP_VerticalLine)
                app.SP_VerticalLine = app.VerticalLine;
            case 'Heat Pulse (H)'
                app.H.Selections.Start_Heat.Value = num2str(app.selTime);
                delete(app.HP_VerticalLine)
                app.HP_VerticalLine = app.VerticalLine;
            case 'End Penetration (E)'
                app.H.Selections.End_Pen.Value = num2str(app.selTime);
                delete(app.EP_VerticalLine)
                app.EP_VerticalLine = app.VerticalLine;
        end
        zoom(app.H_axis_raw, 'on')
        pan(app.H_axis_raw, 'on')
        end
    end


    methods (Access = public)
        
        function writepenfile(app, cruise, pen, inst, sens, therm, datum, lat, lon, write, depth, tilt, station)
            app.H_cruisename=cruise;
            app.H_station=station;
            app.H_penetration=pen;
            app.H_sensor=sens;
            app.H_datum=datum;
            app.H_instrument=inst;
            app.H_thermistors=therm;
            app.H_lat=lat;
            app.H_lon=lon;
            app.H_writepenfile=write;
            app.H_tilt=tilt;
            app.H_depth=depth;

            app.H_quit.Text=cruise;
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


    % INITIAL CONDITIONS %
       
        % Vertical line begins at x = 0
        enableDefaultInteractivity(app.H_axis_raw);
           
        % Intialize Structure % 
        app.S_Axes = struct('Raw',app.H_axis_raw,'Depth',app.H_axis_depth,...
        'Tilt',app.H_axis_tilt,'Accel',app.H_axis_accel); 

        % Structure Containing Axes Limits
        app.S_AxLims = struct('AxLims_Raw',app.H_AxLims_Raw,...
        'AxLims_Depth',app.H_AxLims_Depth,...
        'AxLims_Tilt',app.H_AxLims_Tilt,'AxLims_Acc',app.H_AxLims_Acc);

        % Structure Containing Executable Control Handles and Filter Window Lengths
        app.S_Exe_Controls = struct('Load',app.H_restart,'Crop',app.H_crop,...
        'Export_H2O',app.H_export_H2O,...
        'Export_Penfile',app.H_export_penfile,'Export_Ascii',app.H_export_ascii,...
        'Selection',app.H_Select,'Delete',app.H_Delete,'Filter_WL',app.H_FilterWindowLengths.Value);

        % Structure Containing Handles for Selection Record Numbers
        app.S_Selections = struct('Select',app.H_Select,...
        'Start_Downcast',app.H_StartDown,...
        'End_Downcast',app.H_EndDown,'Start_Upcast',app.H_StartUp,...
        'End_Upcast',app.H_EndUp,'Start_Eqm',app.H_StartEqm,...
        'End_Eqm',app.H_EndEqm,'Start_Pen',app.H_Pen,'Start_Heat',app.H_Heat,...
        'End_Pen',app.H_End);

        % Structure Containing Plot Control Handles and Quit/Screengrab
        app.S_Plot_Controls = struct('Zoom',app.H_zoom,'Pan',app.H_pan,...
        'RawPlot',app.H_rawp,'CleanPlot',app.H_clnp','DecimatedPlot',app.H_decp,...
        'Screengrab',app.H_screengrab,'Quit',app.H_quit,'Legend',app.H_LegendSelect);

        %Structure Containing Handles for Selection Vertical Line Plotting
        app.S_Selections_Lines = struct('Raw',app.H_Selected_raw,'Depth',app.H_Selected_depth',...
        'Tilt',app.H_Selected_tilt,'Accel',app.H_Selected_accel,...
        'Raw_Text',app.H_Selected_raw_text,...
        'Depth_Text',app.H_Selected_depth_text,'Tilt_Text',app.H_Selected_tilt_text,...
        'Accel_Text',app.H_Selected_accel_text, 'Start_Downcast_Line', app.SD_VerticalLine, ...
        'End_Downcast_Line', app.ED_VerticalLine, 'Start_Upcast_Line', app.SU_VerticalLine, ...
        'End_Upcast_Line', app.EU_VerticalLine, 'Start_Eqm_Line', app.SE_VerticalLine, ...
        'End_Eqm_Line', app.EE_VerticalLine, 'Start_Pen_Line', app.SP_VerticalLine, ...
        'Start_Heat_Line', app.HP_VerticalLine, 'End_Pen_Line', app.EP_VerticalLine);

        % Structure Containing Datafile information
        app.S_FileInfo = struct('Filename',app.H_filename,'Start_Date',app.H_start_date,...
        'Start_Time',app.H_start_time,'End_Time',app.H_end_time,...
        'Log_Interval',app.H_logint,'Decay_Time',app.H_decay,'Pulse_Length',app.H_pulse,...
        'No_Thermistors',app.H_NoThermistors);

        % ----- Structure Containing All Previous Structures ----- %
        app.H = struct('Fileinfo',app.S_FileInfo,'Axes',app.S_Axes,...
        'Exe_Controls',app.S_Exe_Controls,...
        'Selections',app.S_Selections,'Selections_Lines',app.S_Selections_Lines,...
        'Plot_Controls',app.S_Plot_Controls,'Axis_Limits',app.S_AxLims,...
        'Error',app.ErrorFlag,'Crop',app.CropFlag);


         % Create data structure to store input and output values %
         app.S_PenHandles = struct('Cruise',app.H_cruisename,'Station',app.H_station,...
         'Pen',app.H_penetration,'Sensor',app.H_sensor,'Datum',app.H_datum,...
         'Inst',app.H_instrument,'Therms',app.H_thermistors,'Lat',app.H_lat,...
         'Lon',app.H_lon,'H_Penfile',app.H_writepenfile,...
         'Tilt',app.H_tilt,'Depth',app.H_depth);


        % Disable all buttons but LOAD %
        app.H_Delete.Enable = 'off';
        app.H_crop.Enable = 'off';
        app.H_export_H2O.Enable = 'off';
        app.H_export_penfile.Enable = 'on';
        app.H_export_ascii.Enable = 'off';

        % Make all edit fields uneditable except filter window %
        %app.H_filename.Editable = 'off';
        app.H_start_time.Editable = 'off';
        app.H_logint.Editable = 'off';
        app.H_start_date.Editable = 'off';
        app.H_end_time.Editable = 'off';
        app.H_decay.Editable = 'off';
        app.H_StartDown.Editable = 'off';
        app.H_EndDown.Editable = 'off';
        app.H_StartUp.Editable = 'off';
        app.H_EndUp.Editable = 'off';
        app.H_StartEqm.Editable = 'off';
        app.H_EndEqm.Editable = 'off';
        app.H_Pen.Editable = 'off';
        app.H_Heat.Editable = 'off';
        app.H_End.Editable = 'off';
        app.H_pulse.Editable = 'off';
        
        % INITIALIZE %
        % Initial zeros or NaN
        app.H_NoThermistors = app.H_Number_of_Thermistors.Value;
        app.All_Zeros = zeros(1,app.H_NoThermistors);
        app.All_NaN = app.All_Zeros/0;
        
        % Set Initial NaN conditions %
        app.H_Selected_raw = app.All_NaN;
        app.H_Selected_depth = app.All_NaN;
        app.H_Selected_accel = app.All_NaN;
        app.H_Selected_tilt = app.All_NaN;
        app.H_Selected_raw_text = app.All_NaN;
        app.H_Selected_depth_text = app.All_NaN;
        app.H_Selected_accel_text = app.All_NaN;
        app.H_Selected_tilt_text = app.All_NaN;
        app.H_LegendSelect = app.All_NaN;
        app.H_AxLims_Raw = app.All_NaN;
        app.H_AxLims_Tilt = app.All_NaN;
        app.H_AxLims_Depth = app.All_NaN;
        app.H_AxLims_Acc = app.All_NaN;
        app.selTime_Vector = app.All_NaN;

        % Set Initial Zero Conditions %
        Traw = 0;
        Tcln = 0;
        Pitch = 0;
        Roll = 0;
        G = 0;
        Tilt = 0;
        Time = 0;
        %app.ErrorFlag = 0;
        %app.CropFlag = 0;

        %app.DATA = struct('Traw',Traw,'Tcln',Tcln,...
       % 'Pitch',Pitch,...
       %% 'Roll',Roll,'G',G,...
       % 'Tilt',Tilt,'Time',Time); 

        app.ErrorFlag = 0;
        app.CropFlag = 0;

        % Initial Paramete labels %
        app.Params = {'text'};
       
        end

        % Button pushed function: H_quit
        function H_quitPushed(app, event)
            close(app.H_Figure)
            clear
        end

        % Button pushed function: H_screengrab
        function H_screengrabPushed(app, event)
            p51_ScreenGrab
        end

        % Button pushed function: Selection_ResetButton
        function Selection_ResetButtonPushed(app, event)
            delete(app.SD_VerticalLine);
            delete(app.ED_VerticalLine);
            delete(app.SU_VerticalLine);
            delete(app.EU_VerticalLine);
            delete(app.SE_VerticalLine);
            delete(app.EE_VerticalLine);
            delete(app.SP_VerticalLine);
            delete(app.HP_VerticalLine);
            delete(app.EP_VerticalLine);
            app.H.Selections.Value=app.H_Select.Items(1);
            app.H.Selections.Start_Downcast.Value='';
            app.H.Selections.End_Downcast.Value='';
            app.H.Selections.Start_Upcast.Value='';
            app.H.Selections.End_Upcast.Value='';
            app.H.Selections.Start_Eqm.Value='';
            app.H.Selections.End_Eqm.Value='';
            app.H.Selections.Start_Pen.Value='';
            app.H.Selections.Start_Heat.Value='';
            app.H.Selections.End_Pen.Value='';
        end

        % Value changed function: H_Select
        function H_SelectValueChanged(app, event)

            app.SelectVerticalLines
            
        end

        % Button pushed function: H_restart
        function H_restartButtonPushed(app, event)
            app.H.Crop = 0;
            app.ErrorFlag=0;
            app.H.Exe_Controls.Crop.Enable='off';
            app.H.Exe_Controls.Delete.Enable='off';
            app.H.Plot_Controls.RawPlot.Enable='off';
            app.H.Plot_Controls.CleanPlot.Enable='off';
            app.H.Plot_Controls.DecimatedPlot.Enable='off';
            app.H.Plot_Controls.Zoom.Enable='off';
            app.H.Plot_Controls.Pan.Enable='off';
            
  %% REST M FILE FIRST
    disp('p51_Reset: clearing...')
            
            % Initialize Selection Vectors for 22 if No_Thermistors hasn't been
            % populated yet
            app.All_Zeros = zeros(1,app.H_Number_of_Thermistors.Value);
            app.All_NaN = app.All_Zeros/0;
            
            %% CLEAR AXES
            
            cla(app.H.Axes.Raw,'reset');
            cla(app.H.Axes.Depth,'reset');
            cla(app.H.Axes.Tilt,'reset');
            cla(app.H.Axes.Accel,'reset');

            %% RESET ALL IF CROP FLAG IS OFF
            if app.H.Crop==0
                app.H.Selections_Lines.Raw                   = app.All_Zeros;
                app.H.Selections_Lines.Depth                 = app.All_Zeros;
                app.H.Selections_Lines.Tilt                  = app.All_Zeros;
                app.H.Selections_Lines.Accel                 = app.All_Zeros;
                app.H.Selections_Lines.Raw_Text              = app.All_Zeros;
                app.H.Selections_Lines.Depth_Text            = app.All_Zeros;
                app.H.Selections_Lines.Tilt_Text             = app.All_Zeros;
                app.H.Selections_Lines.Accel_Text            = app.All_Zeros;

            % Reset Export/Write Buttons
                app.H.Exe_Controls.Export_H2O.Enable='off';
                app.H.Exe_Controls.Export_Penfile.Enable='off';
                app.H.Exe_Controls.Export_Ascii.Enable='off';

            % Reset Datafile Information
                app.H.Fileinfo.Filename.Value = '';
                app.H.Fileinfo.Start_Date.Value='';
                app.H.Fileinfo.Start_Time.Value='';
                app.H.Fileinfo.End_Time.Value='';
                app.H.Fileinfo.Log_Interval.Value='';
                app.H.Fileinfo.Pulse_Length.Value='';
                app.H.Fileinfo.Decay_Time.Value='';

            % Reset Selection info
                app.H.Selections.Select.Enable='on';
                app.H.Selections.Value=app.H_Select.Items(1);
                app.H.Selections.Start_Downcast.Value='';
                app.H.Selections.End_Downcast.Value='';
                app.H.Selections.Start_Upcast.Value='';
                app.H.Selections.End_Upcast.Value='';
                app.H.Selections.Start_Eqm.Value='';
                app.H.Selections.End_Eqm.Value='';
                app.H.Selections.Start_Pen.Value='';
                app.H.Selections.Start_Heat.Value='';
                app.H.Selections.End_Pen.Value='';
            end
            
            %% RESET SOME IF CROP FLAG IS OFF -- NOT SURE THE POINT OF THIS, THERE IS NO DATA MATRIX YET?
            % also don't get why this is necessary since you set
            % app.H.Crop=0 before calling the RESET or LOAD m file at all
            if app.H.Crop==1
                app.H.Selections_Lines.Raw                   = app.All_Zeros;
                app.H.Selections_Lines.Depth                 = app.All_Zeros;
                app.H.Selections_Lines.Tilt                  = app.All_Zeros;
                app.H.Selections_Lines.Accel                 = app.All_Zeros;
                app.H.Selections_Lines.Raw_Text              = app.All_Zeros;
                app.H.Selections_Lines.Depth_Text            = app.All_Zeros;
                app.H.Selections_Lines.Tilt_Text             = app.All_Zeros;
                app.H.Selections_Lines.Accel_Text            = app.All_Zeros;

            % Reset Export/Write Buttons
                app.H.Exe_Controls.Export_H2O.Enable='off';
                app.H.Exe_Controls.Export_Penfile.Enable='off';
                app.H.Exe_Controls.Export_Ascii.Enable='off';

            % Reset Datafile Information
                
                %app.H.Fileinfo.Start_Date.Value = datestr(min(app.DATA.Time),2);
                %app.H.Fileinfo.Start_Time.Value = datestr(min(app.DATA.Time),13);
                %app.H.Fileinfo.End_Time.Value = datestr(min(app.DATA.Time),13);

            % Reset Selection info
                app.H.Selections.Select.Enable='off';
                app.H.Selections.Value=app.H_Select.Items(1);
                app.H.Selections.Start_Downcast.Value='';
                app.H.Selections.End_Downcast.Value='';
                app.H.Selections.Start_Upcast.Value='';
                app.H.Selections.End_Upcast.Value='';
                app.H.Selections.Start_Eqm.Value='';
                app.H.Selections.End_Eqm.Value='';
                app.H.Selections.Start_Pen.Value='';
                app.H.Selections.Start_Heat.Value='';
                app.H.Selections.End_Pen.Value='';
            end            
            return


        end

        % Button pushed function: H_load
        function H_loadPushed(app, event)
            Traw = 0;
            Tcln = 0;
            Pitch = 0;
            Roll = 0;
            G = 0;
            Tilt = 0;
            Time = 0;


            %% LOAD M FILE NEXT  
        % % EXTRACT FILTERING WINDOWS
            filterwindows = str2num(app.H_FilterWindowLengths.Value);
            disp('Filtering...')
            if length(filterwindows)~=3
                uiwait(errordlg('Must Have Three Filter Window Lengths ',...
                'Filter Error','modal'));
                app.ErrorFlag=1;
                return
            end

            wlmedian = filterwindows(:,1);
            wlmean   = filterwindows(:,2);
            wldec    = filterwindows(:,3);

            if rem(wlmean,2)~=0
                uiwait(errordlg('Mean Window Length Must be Even Number',...
                'Filter Error','modal'));
                app.ErrorFlag=1;
                return
            end

        % % INPUT FILE
        % Open gui to select filename
        [filename, pathname] = uigetfile({'*.raw';'*.mat'},'Pick an ROQ Probe Output File');

        % Validate the file/path
        if isequal(filename,0)||isequal(pathname,0)
            disp('File not found')
            H_error = errordlg('File not found or not valid');
            app.ErrorFlag=1;
            return
        else
            f=fullfile(pathname,filename);
        
            % See if it's a mat file or a raw file
            [~,fn,ext]=fileparts(filename);
            if strcmp(ext,'.mat')
                disp([' loading ',fn])
                load(f,'DATA')
            else
                % Get the first line and confirm default header start
                fid = fopen(f,'r');
                if fid<0
                    h_error = errordlg('Unable to open selected file','Error','modal');
                    app.ErrorFlag=1;
                    uiwait(h_error);
                    return
                else
                    line = fgetl(fid);
                    if isempty(line)||~strcmp(line(1),'$')
                        fclose(fid);
                        disp('Invalid File Format');
                        app.ErrorFlag=1;
                        return
                    else
                        % Read in Header Here (TO DO) --> don't understand this. It just makes line equal to the 12th line. Why the loop? - KD
                        for n=1:12
                            line = fgetl(fid);
                        end
                
                        % Determining NUMBER OF THERMISTORS - KD
                            % Option 1 (allow user to manually input number of thermistors
                                app.H.Fileinfo.No_Thermistors.Value = app.H_Number_of_Thermistors.Value;  % Set the No_Thermistors in H.Fileinfo Structure
                            % Option 2 (Mike's):
                                % Read second line and determine number of thermistors 
                                % There are 11 columns of timing, acceleration, etc.
                                % Add 1 for bottom water sensor
                                line         = strtrim(fgetl(fid)); %--> don't understand this. Now it just makes line equal to the 13th line. What was the point of the loop above?
                                ncols        = length(find(isspace(line)));
                                nThermistors = ncols-11+1;
                                app.H.Fileinfo.No_Thermistors.Value = nThermistors;  % Set the No_Thermistors in H.Fileinfo Structure
                            

                        % Fill properties that create vectors of zeros or NaNs the size of the number of thermistors (called foo in Mike's code)
                        app.All_Zeros = zeros(1,app.H.Fileinfo.No_Thermistors.Value);
                        app.All_NaN = app.All_Zeros/0;
                    end
                end
                fclose(fid);

                % % ---------------------  LOAD DATA --------------------- % %
                % CHECK FOR UNIX - USE AWK IF POSSIBLE
        
                %%p51_LoadUnix%%
                [~,fn,~]=fileparts(f);
                h_wait = msgbox('Scanning Data...','Load');
        
                    %%p51_ScanUnix%%
                    if exist('RawData.tmp','file')
                    delete('RawData.tmp');
                    end
                    disp(' Unix - using awk...')
                    % Grab all lines with length > 80 (Data)
                    eval(['!awk ''length > 80'' ',f,' > RawData.tmp'])
                    % Grab all Acomms Report lines
                    eval(['!awk ''/RPT/'' ',f,' > ',fn,'_HReport.dat'])
                    % Grab all Heat Pulse Report lines
                    eval(['!awk ''/HSTART/'' ',f,' > ',fn,'_HPulse.dat'])
                    % Prep textscan for variable number of thermistors
                    NoTherm = app.H.Fileinfo.No_Thermistors.Value;
                    n  = repmat(['%n '],1,NoTherm); %Thermistors
                    s       = repmat(['%s '],1,11);         % Time, Acc, depth
                    fid = fopen('RawData.tmp','r');
                    C   = textscan(fid,[s n]);
                    fclose(fid);
        
                    % Initialize Matrix AllTdata (All Temperature Data)
                    l    = length(C{1});
                    Traw = NaN*zeros(NoTherm+1,l);
        
                    % Assign
                    ymd    = str2num(char(strrep(C{3},'-',' ')));
                    hms    = str2num(char(strrep(C{4},':',' ')));
                    accx   = str2num(char(strrep(C{6},'X','')));
                    accy   = str2num(char(strrep(C{7},'Y','')));
                    accz   = str2num(char(strrep(C{8},'Z','')));
                    pwr    = str2num(char(strrep(C{9},'V','')));
                    z      = str2num(char(strrep(C{10},'D','')));
        
                    for i=1:NoTherm
                        foo = C{11+i}';
                        Traw(i,1:length(foo))=foo;
                    end
        
                    % Restructure Traw with T1 in row 1, incrementing to Twater
                    Traw(NoTherm+1,:)=Traw(1,:);
                    Traw=Traw(2:end,:);
                    %%END of p15_ScanUnix%%
      
                % % QC. Error if number of temperature measurements ~= time. Prompt for CleanRawFile
                if length(Traw)~=length(hms)
                    errorstr=['Timing Error. Click OK to attempt to clean'];
                        dlgname = ['Timing Error'];
                        %uiwait(errordlg(errorstr,dlgname,'modal'));
                        choice = questdlg(errorstr,dlgname,...
                            'OK','Cancel','OK');
        
                    switch choice
                    case 'Cancel'
                        fclose all;close all hidden; clear
                    case 'OK'
                        CleanRawFile(f)
                        [ymd,hms,accx,accy,accz,pwr,z,Traw] = p51_ScanUnix(H,f,fn);
                    %if length(Traw)~=length(hms)
                    %    errorstr=['Timing Error Still Exists - Unable to clean'];
                    %    dlgname = ['Timing Error'];
                    %    uiwait(errordlg(errorstr,dlgname,'modal'));
                    %    fclose all;close all hidden; clear
                    %end
                    end
                end
        
        
                % Remove final timestep from temperature data (logger could have been
                % turned off with a partial line)


                % % Parse Timing
                yr    = ymd(:,1);
                mo    = ymd(:,2);
                dy    = ymd(:,3);
                hr    = hms(:,1);
                mn    = hms(:,2);
                sc    = hms(:,3);

                % % Read in Heat Pulse and Report Data
                Other = {};
                fid = fopen([fn,'_HPulse.dat'],'r');
                i=1;
                while i>0
                    line = fgetl(fid);
                    if line == -1
                        fclose(fid);
                        isave=i-1;
                        i=0;
                        break
                    else
                        Other{i}=strtrim(line);
                        i=i+1;
                    end
                end
                fid = fopen([fn,'_HReport.dat'],'r');
                i=isave+1;
                while i>0
                    line = fgetl(fid);
                    if line==-1
                        fclose(fid);
                        i=0;
                        break
                    else
                        Other{i}=strtrim(line);
                        i=i+1;
                    end
                end


                % % Read in Header and Assign Parameters
                fid=fopen(f,'r');

                % Discard Description and first parameter line (no longer used)
                line = fgetl(fid); line = fgetl(fid);

                % Remaining Header Lines
                headerlines = 11;

                for i=1:headerlines
                    line = strtrim(fgetl(fid));
                    a    = find(isspace(line)==1);
                    Params{i}     = strtrim(line(1:a(end)));
                    ParamsVals(i) = str2num(line(a(end):end));
                end
                fclose(fid);

                % Store Header in Structure PARAMETERS
                app.Params     = Params;
                app.ParamsVals = ParamsVals;
                app.Parameters = struct('Field',{app.Params},'Value',app.ParamsVals);

                % % Remove Temporary Files Generated by AWK
                if exist('RawData.tmp','file')
                    delete('RawData.tmp');
                end
                
                close(h_wait);
       
        %%END of p15_LoadUnix%%


        % % PITCH, ROLL, ACCELERATION, TILT

        % START p51_Acceleration(accx,accy,accz);% --> manually do p51_Acceleration - KD
      
        % Roll
        % Roll = (atan2(accy,-accz)*180)/pi; --> Don't need Roll - KD

        % Pitch
        % Pitch = (atan2(-accx,sqrt(pow2(accy)+pow2(-accz)))*180)/pi; --> Don't need Pitch - KD
        
        % Calculate TILT %
            % Vertical Acceleration: -256 --> +256 == -2g --> + 2g
            G = accz*(2/256);

            % Vertical Acceleration deviation from 1 G
            Gdev = abs(1-abs(G));

            % Calculate Tilt : -1G = 0 deg; 0G = 90 deg
            Tilt = normalize(Gdev,0,90);
         
        % END p51_Acceleration(accx,accy,accz)


        % % QC DEPTH
        a=find(z<0); % changed Z's that are less than 0 to NaN
        if ~isempty(a)
            z(a)=NaN;
        end
        
        % QC Year
        if yr(1)==0
            yr(1:end)=21;
        end
            
        if yr(1)<2000
            yr=yr+2000;
        end

        % % ----------------------------------------------- % %
        %                                                   %
        %                2020 Filtering Change              %
        %                                                   %
        %     Take only Unique time values                  %                           %
        %                                                   %
        
        timeNum  = datenum(yr,mo,dy,hr,mn,sc); 
        a        = find(unique(timeNum));  % To handle duplicate times (take this out of main code) - KD
        timeNumU = timeNum(a);
        accxU    = accx(a);
        accyU    = accy(a);
        acczU    = accz(a);
        %pitchU   = Pitch(a);
        pwrU     = pwr(a);
        %rollU    = Roll(a);
        tiltU    = Tilt(a);
        gU       = G(a);
        zU       = z(a);
        TrawU    = Traw(:,a);
       
        
        % % Clean / Filter / Decimate

        % START p51_CFD(Traw, wlmedian,wlmean,wldec,NoTherm,timeNumU)
        
            Tallf1    = Traw;
            b         = Tallf1<=0; % I changes this variable from a to b so that the variable a above remains the same (bc is used later)
            Tallf1(b) = NaN;
            
            % % Despike by taking running median
            disp('... running median filtering...')
            for i = 1:NoTherm
                eval(['Tallf2(',int2str(i),',:)=movmedian(Tallf1(',int2str(i),',:),wlmedian);'])
            end
            % % Smooth by taking running mean
            disp('... running mean filtering...')
            for i = 1:NoTherm
                eval(['Tallf2(',int2str(i),',:)=rbmmed(Tallf2(',int2str(i),',:),wlmean);'])
            end
            
            % Assign median filtered values to matrix Tcln (CLEAN)
            Tcln = Tallf2;

            % Flip time
            timeNumU = timeNumU';
            
            % Decimate and assign values to matrix Tdec (DECIMATED)
            disp(['...decimating to ',int2str(wldec),'-s interval...'])
            n=1:wldec:length(Tcln(1,:));
            Tdec=Tcln(:,n);
            timeDec=timeNumU(n);
          
         % END START p51_CFD %


        % % TIMING
        % Create Datenumber & Record Counter
        timestr = datestr(timeNumU);
        
        Time = timeNumU;

        Record       = 1:1:length(Time);
        RecordU      = Record(a);


        % % ASSIGN DECIMATED DATA

        n          = 1:wldec:length(TrawU);
        Time_dec   = timeDec;
        Record_dec = RecordU(n);
        %Pitch_dec  = pitchU(n);
        %Roll_dec   = rollU(n);
        G_dec      = gU(n);
        Tilt_dec   = tiltU(n);
        Power_dec  = pwrU(n);
        Depth_dec  = zU(n);

        % % DISPLAY ACOMMS DATA - HEAT PULSE AND JOULES
        disp('Acomms Data')
        disp(Other')

        % % FOLD DATA INTO STRUCTURE

        % Store Acoustic Comms in Structure Acomms
        S_Acomms = struct('Acomms',{Other'});

        app.DATA = struct('Traw',TrawU,'Tcln',Tcln,'Tdec',Tdec,...
            'G',gU,'G_dec',G_dec,'Tilt',tiltU,'Tilt_dec',Tilt_dec,...
            'Time',timeNumU,'Time_dec',Time_dec,...
            'Record',RecordU,'Record_dec',Record_dec,...
            'Power',pwrU,'Power_dec',Power_dec,...
            'Depth',zU,'Depth_dec',Depth_dec,...
            'Acc_x',accxU,...
            'Acc_y',accyU,...
            'Acc_z',acczU,...
            'Parameters',{app.Parameters},...
            'Acomms',{Other'});

        % % SAVE AS MAT FILE
        fn=[fn,'.mat'];
        disp(['saving ',fn])
        VarDATA = app.DATA;
        save(fn,'VarDATA');
    end
end


% % ASSIGN FILE INFO
app.H.Fileinfo.Start_Date.Value=datestr(app.DATA.Time(1),2);
app.H.Fileinfo.Start_Time.Value=datestr(app.DATA.Time(1),13);
app.H.Fileinfo.End_Time.Value=datestr(app.DATA.Time(end),13);
%H.Fileinfo.Filename.String=filename(11:end); % Cut off typical client_FUG17_...
app.H.Fileinfo.Filename.Value=filename;
app.H.Fileinfo.HFID=filename(17:end);
app.H.Fileinfo.Log_Interval.Value=num2str(app.DATA.Parameters.Value(6));
app.H.Fileinfo.Decay_Time.Value=num2str(app.DATA.Parameters.Value(5));
app.H.Fileinfo.Pulse_Length.Value=num2str(app.DATA.Parameters.Value(3));


% START p51_Plot_ROQ(H,DATA) %

disp('p51_Plot_ROQ: plotting data...')

% Determine Number of Thermistors
NoTherm = app.H.Fileinfo.No_Thermistors.Value;

% Extract Decimated Data for plotting
T_dec          = app.DATA.Tdec;
Depth_dec      = app.DATA.Depth_dec;
%Pitch_dec      = app.DATA.Pitch_dec;
%Roll_dec       = app.DATA.Roll_dec;
G_dec          = app.DATA.G_dec;
Tilt_dec       = app.DATA.Tilt_dec;
Time_dec       = app.DATA.Time_dec;
record_dec     = app.DATA.Record_dec;

% Extract Clean Data for plotting
T_cln          = app.DATA.Tcln;

% Extract Raw Data for plotting
T      = app.DATA.Traw;
Depth  = app.DATA.Depth;
%Pitch  = app.DATA.Pitch;
%Roll   = app.DATA.Roll;
G      = app.DATA.G;
Tilt   = app.DATA.Tilt;
Time   = app.DATA.Time;
record = app.DATA.Record;

% Enable buttons and fill in text
app.H.Exe_Controls.Export_Ascii.Enable='on';
app.H.Exe_Controls.Crop.Enable='on';
app.H.Plot_Controls.Screengrab.Enable='on';
app.H.Selections.Select.Enable='on';
app.H.Exe_Controls.Delete.Enable='on';

%% COLORMAP
colormap(app.H_axis_raw, 'default');
CMap= colormap(app.H_axis_raw, 'jet');
CMap = flipud(CMap);
%CMap = interp1(1:256,CMap,1:256/(NoTherm-1):256); % 'jet' colormap for Matlab version 2019a and later has size 256x3 RBG matrix
CMap = interp1([1:64],CMap,[1:64/(NoTherm-1):64]); %'jet' colormap for Matlab version 2018b or earlier has size 64x3 RBG matrix
colormap(app.H_axis_raw, CMap);


%% Plot DECIMATED (median filtered) TEMPERATURE/OHM DATA AS o's

% Force records to increment from 1
%record_dec = 1:1:length(record_dec);

% Vector to store plot handles for each thermistor 
h_axDec=zeros(1,NoTherm);
leg = zeros(1,NoTherm);
i=1;
while i<=NoTherm-1
    % Plot by time
    h_axDec(i) = plot(app.H_axis_raw, Time_dec,T_dec(i,:),'-o','markersize',2,...
        'color',CMap(i,:),'markerfacecolor',CMap(i,:));    
    hold(app.H_axis_raw, 'on');
    leg(i) = i;
    i=i+1;
end

% Plot DECIMATED Bottom Water
h_axDec(i) = plot(app.H_axis_raw, Time_dec,T_dec(NoTherm,:),'k-x','markersize',2);
%leg(i) = i;


% Save plot handles
app.H.Plot_Controls.DecimatedPlot.UserData = h_axDec;

% Enable Plot Control Toggle
app.H.Plot_Controls.DecimatedPlot.Enable='on';

%Legend
leg = compose('Thermistor%3.0f', leg);
app.H.Plot_Controls.Legend = leg;
app.H.Plot_Controls.Legend{NoTherm} = 'Bottom Water';
lgnd = legend(app.H_axis_raw, app.H.Plot_Controls.Legend, 'AutoUpdate', 'off', 'Location', 'northeast');
lgnd.FontSize = 8;


% Raw Axis Limits
V_T = axis(app.H_axis_raw);

% Labels, Date ticks, and Title
if T_dec(5,10)>100
    ylabel(app.H_axis_raw, 'Resistance (Ohm)');
else
    ylabel(app.H_axis_raw, 'Temperature (^oC)');
end
set(app.H_axis_raw,'color',[0.9 0.9 0.9]);

% Keep Visible or Hide
if app.H.Plot_Controls.DecimatedPlot.Value == 0
    for i=1:NoTherm
        set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','off');
    end
end

%% Plot CLEAN Data
% Plot CLEAN (median filtered) data as x's
% Vector to store plot handles for each thermistor 
h_axCln=zeros(1,NoTherm);
i=1;
while i<=NoTherm-1
    h_axCln(i) = plot(app.H_axis_raw, Time,T_cln(i,:),'o','markersize',2,...
        'color',CMap(i,:),'markerfacecolor',CMap(i,:));
    hold(app.H_axis_raw, "on");
    i=i+1;
end
% Plot CLEAN Bottom Water
h_axCln(i) = plot(app.H_axis_raw, Time,T_cln(NoTherm,:),'kv','markersize',2,'markerfacecolor','b');

% Turn Bottom Water off;
set(h_axCln(i),'Visible','off')


% Save plot handles
app.H.Plot_Controls.CleanPlot.UserData = h_axCln;

% Enable Plot Control Toggle
app.H.Plot_Controls.CleanPlot.Enable='on';

% Keep Visible or Hide
if app.H.Plot_Controls.CleanPlot.Value == 0
%    set(H.Plot_Controls.Legend.UserData(2),'Visible','off');
    for i=1:NoTherm
        set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','off');
    end
    
end

%% RAW DATA PLOT

% Plot RAW data as x's
h_axRaw=zeros(1,NoTherm);
i=1;
while i<=NoTherm-1
    h_axRaw(i) = plot(app.H_axis_raw, Time,T(i,:),'x','markersize',2,'color',CMap(i,:));
    hold(app.H_axis_raw, "on");
    i=i+1;
end
% Plot RAW Bottom Water
h_axRaw(i) = plot(app.H_axis_raw, Time,T(NoTherm,:),'kv','markersize',2);

% Turn Bottom Water off;
set(h_axRaw(i),'Visible','off')

% Save plot handles
app.H.Plot_Controls.RawPlot.UserData = h_axRaw;

% Enable Plot Control Toggle
app.H.Plot_Controls.RawPlot.Enable='on';
app.H.Plot_Controls.RawPlot.Value=1;

%% DEPTH - DECIMATED

% Plot depth data 
h_axDep=plot(app.H_axis_depth, Time_dec,-Depth_dec,'b');
legend(app.H_axis_depth, 'Depth','Location','NorthEast','Orientation','Horizontal')

% Depth Axis Limits
V_Zd = axis(app.H_axis_depth);
set(app.H_axis_depth,'color',[0.9 0.9 0.9]);

% Add the axes data to the Structure
app.H.Axes.Depth.UserData = h_axDep;

%% ACCELERATION - DECIMATED

% Plot Acceleration data 
h_axAcc=plot(app.H_axis_accel, Time_dec,G_dec,'k');
legend(app.H_axis_accel, 'Acceleration','Location','NorthEast','Orientation','Horizontal')

% Acceleration Axis Limits
V_Za = axis(app.H_axis_accel);
set(app.H_axis_accel,'color',[0.9 0.9 0.9]);

% Add the axes data to the Structure
app.H.Axes.Accel.UserData = h_axAcc;


%% TILT PLOT - DECIMATED
% Plot Tilt
plot(app.H_axis_tilt, Time_dec,Tilt_dec,'r'); hold(app.H_axis_tilt, "on")

% Tilt Axis Limits
V_Tilt = axis(app.H_axis_tilt);

legend(app.H_axis_tilt, 'Tilt','Location','NorthEast', ...
    'Orientation','Horizontal');
set(app.H_axis_tilt,'color',[0.9 0.9 0.9]);

%% STORE ORIGINAL AXES LIMITS
app.H.Axis_Limits.AxLims_Raw.Value=V_T;
app.H.Axis_Limits.AxLims_Depth.Value=V_Zd;
app.H.Axis_Limits.AxLims_Acc.Value=V_Za;
app.H.Axis_Limits.AxLims_Tilt.Value=V_Tilt;

%% Link Axes and Assign Dynamic Date Ticks
tempAx = app.H.Axes.Raw;
accelAx = app.H.Axes.Accel;
depthAx = app.H.Axes.Depth;
tiltAx = app.H.Axes.Tilt;
ax=[tempAx accelAx depthAx tiltAx];
%linkaxes(ax,'x'); does not work in 2018
% dynamicDateTicks(ax,'HH:MM'); --> this is Mike's. I edited this function
% below to do the same thing but be compatible with App Designer
mdformat = 'HH:MM:SS'; % variable for month/day format 

% Apply datetick to all axes in axH, and store any linking information
% temperature axis
axH = tempAx;
axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
for i = 1:length(axH)
    datetick(axH(i), 'x');
    axesInfo.Linked = axH;
    axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
    set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
end

% acceleration axis
axH = accelAx;
axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
for i = 1:length(axH)
    datetick(axH(i), 'x');
    axesInfo.Linked = axH;
    axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
    set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
end

% depth axis
axH = depthAx;
axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
for i = 1:length(axH)
    datetick(axH(i), 'x');
    axesInfo.Linked = axH;
    axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
    set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
end

% tilt axis
axH = tiltAx;
axesInfo.Type = 'dateaxes'; % Information stored in axes userdata indicating that these are date axes
for i = 1:length(axH)
    datetick(axH(i), 'x');
    axesInfo.Linked = axH;
    axesInfo.mdformat = mdformat; % Remember mm/dd format for each axes
    set(axH(i), 'UserData', axesInfo); % Store the fact that this is a date axes and its link & mm/dd information in userdata
end
        end

        % Button pushed function: Selection_BeginButton
        function Selection_BeginButtonButtonPushed(app, event)

           app.SelectVerticalLines
        end

        % Value changed function: H_decp
        function H_decpValueChanged(app, event)
        DecValue = app.H_decp.Value;
            %p51_PlotSelect toggles Decimated, Clean, and Raw Data plot on/off
        if DecValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(1),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','on');
            end
        end

        end

        % Value changed function: H_clnp
        function H_clnpValueChanged(app, event)
        CleanValue = app.H_clnp.Value;
            % Clean Plot
        if CleanValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(2),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','on');
            end
        end
        end

        % Value changed function: H_rawp
        function H_rawpValueChanged(app, event)
        RawValue = app.H_rawp.Value;
        if RawValue == 0
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','off');
            end
        else
            %set(H.Plot_Controls.Legend.UserData(3),'Visible','on');
            for i=1:app.H.Fileinfo.No_Thermistors.Value
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','on');
            end
        end
        end

        % Value changed function: H_zoom
        function H_zoomValueChanged(app, event)
            ZoomValue = app.H_zoom.Value;
            zoom(app.H_axis_raw, 'off');
            if ZoomValue==1
                zoom(app.H_axis_raw, 'on')
            else
                zoom(app.H_axis_raw, 'off');
            end
        end

        % Value changed function: H_pan
        function H_panValueChanged(app, event)
            PanValue = app.H_pan.Value;
            pan(app.H_axis_raw, 'off');
            if PanValue==1
                pan(app.H_axis_raw, 'on');
            else
                pan(app.H_axis_raw, 'off');
            end
        end

        % Button pushed function: H_crop
        function H_cropButtonPushed(app, event)
            %p51_Crop - Crop data before downcast and after upcast

            % Set CropFlag to 1
            app.H.Crop=1;

            % Set axes and get initial limits
            V=axis(app.H_axis_raw);

            % Start point
            prompt = sprintf('Click OK to Select Starting Crop Point (on temperature axis)');
            uiwait(msgbox(prompt,'CROP START','modal'))
            [xstart,~]=app.H_axis_raw.CurrentPoint;
            plot(app.H_axis_raw, [xstart xstart],[V(3) V(4)],'k--')

            % End point
            prompt = sprintf('Click OK to Select Ending Crop Point (on temperature axis)');
            uiwait(msgbox(prompt,'CROP END','modal'))
            [xend,~]=ginput(app.H_axis_raw, 1);
            plot(app.H_axis_raw, [xend xend],[V(3) V(4)],'k--')

            % Corresponding records between start and end
            a = find(app.DATA.Time>=xstart & app.DATA.Time<=xend);          % All Data
            b = find(app.DATA.Time_dec>=xstart & app.DATA.Time_dec<=xend);  % Decimated Data


            % Only Plotting Decimated Data
            %record = 1:1:length(DATA.Time_dec);
            %b      = find(record>=xstart & record<=xend); 

            % Crop all Records in Structure DATA
            app.DATA.Traw   = app.DATA.Traw(:,a);
            app.DATA.Tcln   = app.DATA.Tcln(:,a);
            app.DATA.Pitch  = app.DATA.Pitch(a);
            app.DATA.Roll   = app.DATA.Roll(a);
            app.DATA.G      = app.DATA.G(a);
            app.DATA.Tilt   = app.DATA.Tilt(a);
            app.DATA.Time   = app.DATA.Time(a);
            app.DATA.Record = app.DATA.Record(a);
            app.DATA.Power  = app.DATA.Power(a);
            app.DATA.Depth  = app.DATA.Depth(a);

            app.DATA.Tdec       = app.DATA.Tdec(:,b);
            app.DATA.Pitch_dec  = app.DATA.Pitch_dec(b);
            app.DATA.Roll_dec   = app.DATA.Roll_dec(b);
            app.DATA.G_dec      = app.DATA.G_dec(b);
            app.DATA.Tilt_dec   = app.DATA.Tilt_dec(b);
            app.DATA.Time_dec   = app.DATA.Time_dec(b);
            app.DATA.Record_dec = app.DATA.Record_dec(b);
            app.DATA.Power_dec  = app.DATA.Power_dec(b);
            app.DATA.Depth_dec  = app.DATA.Depth_dec(b);

            %% SAVE AS MAT FILE
            DataVar = app.DATA;
            [~,fn,~] = fileparts(app.H.Fileinfo.Filename.String);
            fn=[fn,'_Crop.mat'];
            disp(['saving ',fn])
            save(fn,'DataVar');
        end

        % Button pushed function: H_Delete
        function H_DeleteButtonPushed(app, event)
            % Turn off zoom and pan
            pan(app.H_axis_raw, "off");
            zoom(app.H_axis_raw, "off");
            
            % Extract time
            % Extract Time
            Time     = app.DATA.Time;       % For Raw and Cln
            Time_dec = app.DATA.Time_dec;   % For Decimated

            disableDefaultInteractivity(app.H_axis_raw)
            roiSelect = drawfreehand(app.H_axis_raw);
            drawnow
            x_selected = roiSelect.Position(:,1); % this works. it creates a vector of x values inside the drawing
            
            %deletedTimes = find(app.DATA.Time_dec == x_selected);
            %deletedTemps = app.DATA.T_dec(deletedTimes);
            %app.
                

            % Only Operate on Visible Data : Set Selected Points to NAN
            % Decimated
            if app.H.Plot_Controls.DecimatedPlot.Value==1
                for i=1:length(x_selected)
                    xvals = cell2mat(x_selected{i});
                    for j=1:length(xvals)
                        val=xvals(j);
                        a=find(Time_dec==val);
                        app.DATA.Tdec(i,a)=NaN;
                    end
                end
            end
            
            % Clean
            if app.H_clnp==1
                for i=1:length(x_selected)
                    xvals = cell2mat(x_selected(i));
                    for j=1:length(xvals)
                        val=xvals(j);
                        a=find(Time==val);
                        app.DATA.Tcln(i,a)=NaN;
                    end
                end
            end
            
            % Raw
            if app.H_rawp==1
                for i=1:length(x_selected)
                    xvals = cell2mat(x_selected(i));
                    for j=1:length(xvals)
                        val=xvals(j);
                        a=find(Time==val);
                        app.DATA.Traw(i,a)=NaN;
                    end
                end
            end
            delete(roiSelect)
        end

        % Button pushed function: H_export_penfile
        function H_export_penfileButtonPushed(app, event)
        % Set input values %   
            Depth = app.DATA.Depth_dec;
            Depth = Depth';
            Time = app.DATA.Time_dec;
            Tilt = app.DATA.Tilt_dec;
            record = app.DATA.Record_dec;
            record = record';
            app.H_cruisename = '';
            app.H_station = '';
            app.H_penetration = '';
            app.H_sensor = '';
            app.H_datum = '';
            app.H_instrument = '';
            app.H_thermistors = '';
            app.H_lat = '';
            app.H_lon = '';
            app.H_writepenfile = '';
            app.H_tilt = '';
            app.H_depth = '';

         % Create data structure to store input and output values %
         S_PenHandles = struct('Cruise',app.H_cruisename,'Station',app.H_station,...
         'Pen',app.H_penetration,'Sensor',app.H_sensor,'Datum',app.H_datum,...
         'Inst',app.H_instrument,'Therms',app.H_thermistors,'Lat',app.H_lat,...
         'Lon',app.H_lon,'H_Penfile',app.H_writepenfile,...
         'Tilt',app.H_tilt,'Depth',app.H_depth);

         % Define penetration measurement Start and End %
            msmt_start = datenum(app.H.Selections.Start_Pen.Value, 'mm/dd/yyyy HH:MM:SS');
            msmt_end = datenum(app.H.Selections.End_Pen.Value, 'mm/dd/yyyy HH:MM:SS');

         % Check year and correct %
            loggerStart = datevec(datestr(Time));
            loggerYear = loggerStart(1,1);
            if loggerYear < 2021
                loggerStart(:,1)=loggerStart(:,1)+2000;
                Time=datenum(loggerStart);
            end
         
          % Find mean depth and tilt records within measurement period
               a = find(Time>=msmt_start & Time<=msmt_end);
               if ~isempty(a)
                   pen_depth = mean(Depth(a), 'omitnan');
                   pen_tilt = mean(Tilt(a), 'omitnan');
                   pen_tilt = sprintf('%2.1f', pen_tilt);
                   pen_tilt = str2double(pen_tilt);
               else
                   errordlg('No Data in Time Window', 'Error', 'modal')
                   return
               end
           
           % Set these values correct in the data structures %
               app.H_instrument.Value = 'logger';
               app.H_tilt.Value = pen_tilt;
               app.H_depth.Value = pen_depth;


           % Create pen file app call %
               % Disable "Create" pushbutton so user cannot create multiple dialog
               % boxes
                app.H_export_penfile.Enable="off";
               % 
              %  app.SlugPen_CreatePenFileDialogBox_working=SlugPen_CreatePenFileDialogBox_working(app);

           % WRITE PENFILE CODE %
           disp('p51_WritePenfile: writing TAP and PEN files...')

           % Number of Thermistors
            NoTherms=app.H.Fileinfo.No_Thermistors.Value;

            % Temperature Data
            T      = app.DATA.Tdec;
            Tilt   = app.DATA.Tilt_dec;
            Depth  = app.DATA.Depth_dec;
            record = app.DATA.Record_dec;
            Time   = app.DATA.Time_dec;

            % Penfile Name
            pen_name        = app.S_PenHandles.Pen;
            stn_name        = app.S_PenHandles.Station;
            penfile_name    = [stn_name pen_name];

            % Consistent definitions with ROQ15
            StationName       = app.S_PenHandles.Station;
            Penetration       = app.S_PenHandles.Pen;
            Cruisename        = app.S_PenHandles.Client;
            Latitude          = app.S_PenHandles.Lat;
            Longitude         = app.S_PenHandles.Lon;
            Depth_mean        = app.S_PenHandles.Depth;
            Tilt_mean         = app.S_PenHandles.Tilt;
            LoggerID          = app.S_PenHandles.Inst;
            ProbeID           = app.S_PenHandles.Sensor;
            NumberOfSensors   = app.S_PenHandles.Therms;
     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.H_Figure = uifigure;
            app.H_Figure.Name = 'SlugPen';
            app.H_panel_controls = uipanel(app.H_Figure);
            app.H_panel_controls.Tooltip = {''};
            app.H_panel_info = uipanel(app.H_panel_controls);
            app.H_panel_info.Title = 'Datafile Information';
            app.H_filename = uieditfield(app.H_panel_info, 'text');
            app.H_start_date = uieditfield(app.H_panel_info, 'text');
            app.H_start_time = uieditfield(app.H_panel_info, 'text');
            app.H_end_time = uieditfield(app.H_panel_info, 'text');
            app.H_logint = uieditfield(app.H_panel_info, 'text');
            app.H_decay = uieditfield(app.H_panel_info, 'text');
            app.H_pulse = uieditfield(app.H_panel_info, 'text');
            app.FilenameLabel = uilabel(app.H_panel_info);
            app.FilenameLabel.Text = 'Filename: ';
            app.StartTimeEditFieldLabel = uilabel(app.H_panel_info);
            app.StartTimeEditFieldLabel.Text = 'Start Time:';
            app.LogIntsEditFieldLabel = uilabel(app.H_panel_info);
            app.LogIntsEditFieldLabel.Text = 'Log Int (s):';
            app.PulsesecEditFieldLabel = uilabel(app.H_panel_info);
            app.PulsesecEditFieldLabel.Text = 'Pulse (sec): ';
            app.StartDateEditFieldLabel = uilabel(app.H_panel_info);
            app.StartDateEditFieldLabel.Text = 'Start Date:';
            app.EndTimeEditFieldLabel = uilabel(app.H_panel_info);
            app.EndTimeEditFieldLabel.Text = 'End Time:';
            app.DecayminEditFieldLabel = uilabel(app.H_panel_info);
            app.DecayminEditFieldLabel.Text = 'Decay (min):';
            app.H_panel_selected = uipanel(app.H_panel_controls);
            app.H_panel_selected.Title = 'Selections Information';
            app.H_Select = uidropdown(app.H_panel_selected);
            app.H_Select.Items = {'Downcast Start', 'Downcast End', 'Upcast Start', 'Upcast End', 'Start Equilibrium (W1)', 'End Equilibrium (W2)', 'Start Penetration (P)', 'Heat Pulse (H)', 'End Penetration (E)'};
            app.H_Select.ValueChangedFcn = createCallbackFcn(app, @H_SelectValueChanged, true);
            app.H_Select.Tooltip = {'Click to graphically select upcast and downcast data for creating penfiles and water column files'};
            app.H_Select.Value = 'Downcast Start';
            app.H_eqpenhp_label = uilabel(app.H_panel_selected);
            app.H_eqpenhp_label.Text = 'Equilibrium, Penetration, Heat Pulse';
            app.H_StartDown = uieditfield(app.H_panel_selected, 'text');
            app.H_EndDown = uieditfield(app.H_panel_selected, 'text');
            app.H_StartUp = uieditfield(app.H_panel_selected, 'text');
            app.H_EndUp = uieditfield(app.H_panel_selected, 'text');
            app.H_StartEqm = uieditfield(app.H_panel_selected, 'text');
            app.H_EndEqm = uieditfield(app.H_panel_selected, 'text');
            app.H_Pen = uieditfield(app.H_panel_selected, 'text');
            app.H_Heat = uieditfield(app.H_panel_selected, 'text');
            app.H_End = uieditfield(app.H_panel_selected, 'text');
            app.UpcastDowncastLabel = uilabel(app.H_panel_selected);
            app.UpcastDowncastLabel.Text = 'Upcast / Downcast';
            app.DowncastStartLabel = uilabel(app.H_panel_selected);
            app.DowncastStartLabel.Text = 'Downcast Start:       ';
            app.DowncastEndEditFieldLabel = uilabel(app.H_panel_selected);
            app.DowncastEndEditFieldLabel.Text = 'Downcast End:';
            app.UpcastStartEditFieldLabel = uilabel(app.H_panel_selected);
            app.UpcastStartEditFieldLabel.Text = 'Upcast Start:';
            app.UpcastEndEditFieldLabel = uilabel(app.H_panel_selected);
            app.UpcastEndEditFieldLabel.Text = 'Upcast End: ';
            app.StartEquilibriumW1EditFieldLabel = uilabel(app.H_panel_selected);
            app.StartEquilibriumW1EditFieldLabel.Text = 'Start Equilibrium (W1): ';
            app.EndEquilibriumW2EditFieldLabel = uilabel(app.H_panel_selected);
            app.EndEquilibriumW2EditFieldLabel.Text = 'End Equilibrium (W2): ';
            app.StartPenetrationPEditFieldLabel = uilabel(app.H_panel_selected);
            app.StartPenetrationPEditFieldLabel.Text = 'Start Penetration (P): ';
            app.HeatPulseHEditFieldLabel = uilabel(app.H_panel_selected);
            app.HeatPulseHEditFieldLabel.Text = 'Heat Pulse (H): ';
            app.EndPenetrationEEditFieldLabel = uilabel(app.H_panel_selected);
            app.EndPenetrationEEditFieldLabel.Text = 'End Penetration (E): ';
            app.Selection_ResetButton = uibutton(app.H_panel_selected, 'push');
            app.Selection_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Selection_ResetButtonPushed, true);
            app.Selection_ResetButton.Text = 'Reset';
            app.Selection_BeginButton = uibutton(app.H_panel_selected, 'push');
            app.Selection_BeginButton.ButtonPushedFcn = createCallbackFcn(app, @Selection_BeginButtonButtonPushed, true);
            app.Selection_BeginButton.Text = 'Begin';
            app.H_panel_plotcontrols = uipanel(app.H_panel_controls);
            app.H_panel_plotcontrols.Title = 'Plot Controls';
            app.H_decp = uicheckbox(app.H_panel_plotcontrols);
            app.H_decp.ValueChangedFcn = createCallbackFcn(app, @H_decpValueChanged, true);
            app.H_decp.Text = 'Decimate';
            app.H_clnp = uicheckbox(app.H_panel_plotcontrols);
            app.H_clnp.ValueChangedFcn = createCallbackFcn(app, @H_clnpValueChanged, true);
            app.H_clnp.Text = 'Clean';
            app.H_rawp = uicheckbox(app.H_panel_plotcontrols);
            app.H_rawp.ValueChangedFcn = createCallbackFcn(app, @H_rawpValueChanged, true);
            app.H_rawp.Text = 'Raw';
            app.H_zoom = uicheckbox(app.H_panel_plotcontrols);
            app.H_zoom.ValueChangedFcn = createCallbackFcn(app, @H_zoomValueChanged, true);
            app.H_zoom.Text = 'Zoom';
            app.H_pan = uicheckbox(app.H_panel_plotcontrols);
            app.H_pan.ValueChangedFcn = createCallbackFcn(app, @H_panValueChanged, true);
            app.H_pan.Text = 'Pan';
            app.H_quit = uibutton(app.H_panel_controls, 'push');
            app.H_quit.ButtonPushedFcn = createCallbackFcn(app, @H_quitPushed, true);
            app.H_quit.Text = 'Quit';
            app.H_screengrab = uibutton(app.H_panel_controls, 'push');
            app.H_screengrab.ButtonPushedFcn = createCallbackFcn(app, @H_screengrabPushed, true);
            app.H_screengrab.Text = 'Screengrab';
            app.DataControlsPanel = uipanel(app.H_panel_controls);
            app.DataControlsPanel.Title = 'Data Controls';
            app.H_crop = uibutton(app.DataControlsPanel, 'push');
            app.H_crop.ButtonPushedFcn = createCallbackFcn(app, @H_cropButtonPushed, true);
            app.H_crop.Text = 'Crop';
            app.H_export_H2O = uibutton(app.DataControlsPanel, 'push');
            app.H_export_H2O.Text = 'Create H2O Column File(s)';
            app.H_export_penfile = uibutton(app.DataControlsPanel, 'push');
            app.H_export_penfile.ButtonPushedFcn = createCallbackFcn(app, @H_export_penfileButtonPushed, true);
            app.H_export_penfile.Text = 'Create Penfile';
            app.H_export_ascii = uibutton(app.DataControlsPanel, 'push');
            app.H_export_ascii.Text = 'Create a ASCII File';
            app.H_Delete = uibutton(app.DataControlsPanel, 'push');
            app.H_Delete.ButtonPushedFcn = createCallbackFcn(app, @H_DeleteButtonPushed, true);
            app.H_Delete.Text = 'Select / Delete';
            app.NumberofThermistorsEditFieldLabel = uilabel(app.DataControlsPanel);
            app.NumberofThermistorsEditFieldLabel.Text = 'Number of Thermistors:';
            app.H_Number_of_Thermistors = uieditfield(app.DataControlsPanel, 'numeric');
            app.FilterWindowLengthsEditFieldLabel = uilabel(app.DataControlsPanel);
            app.FilterWindowLengthsEditFieldLabel.Text = 'Filter Window Lengths:';
            app.H_FilterWindowLengths = uieditfield(app.DataControlsPanel, 'text');
            app.H_FilterWindowLengths.Value = '2 6 10';
            app.H_restart = uibutton(app.DataControlsPanel, 'push');
            app.H_restart.ButtonPushedFcn = createCallbackFcn(app, @H_restartButtonPushed, true);
            app.H_restart.Text = 'Restart';
            app.H_load = uibutton(app.DataControlsPanel, 'push');
            app.H_load.ButtonPushedFcn = createCallbackFcn(app, @H_loadPushed, true);
            app.H_load.Text = 'Load and Filter';
            app.Panel = uipanel(app.H_Figure);
            app.H_axis_depth = uiaxes(app.Panel);
            title(app.H_axis_depth, 'Depth vs. Time')
            xlabel(app.H_axis_depth, 'Time (min:s)')
            ylabel(app.H_axis_depth, 'Depth (m-FW)')
            app.H_axis_accel = uiaxes(app.Panel);
            title(app.H_axis_accel, 'Acceleration vs. Time')
            xlabel(app.H_axis_accel, 'Time (min:s)')
            ylabel(app.H_axis_accel, 'g (m/s^2)')
            app.H_axis_tilt = uiaxes(app.Panel);
            title(app.H_axis_tilt, 'Tilt vs. Time')
            xlabel(app.H_axis_tilt, 'Time (min:s)')
            ylabel(app.H_axis_tilt, 'Tilt (°)')
            app.H_axis_raw = uiaxes(app.Panel);
            title(app.H_axis_raw, 'Time Series')
            xlabel(app.H_axis_raw, 'Time (min:s)')
            ylabel(app.H_axis_raw, 'Temperature (°C)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = SlugPen_AppDesigner2018_working

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.H_Figure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.H_Figure)
        end
    end
end